classdef PF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ColorEditField               matlab.ui.control.EditField
        ColorEditFieldLabel          matlab.ui.control.Label
        DanioEditField               matlab.ui.control.EditField
        DanioEditFieldLabel          matlab.ui.control.Label
        EstadodelataEditField        matlab.ui.control.EditField
        EstadodelataEditFieldLabel   matlab.ui.control.Label
        EstatusEditField             matlab.ui.control.EditField
        EstatusEditFieldLabel        matlab.ui.control.Label
        ColordelataLamp              matlab.ui.control.Lamp
        ColordelataLampLabel         matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        EntrenandoLabel              matlab.ui.control.Label
        TrainButton                  matlab.ui.control.Button
        RecuentogeneralLabel         matlab.ui.control.Label
        HistorialdeactividadesLabel  matlab.ui.control.Label
        UITable_2                    matlab.ui.control.Table
        UITable                      matlab.ui.control.Table
        StopButton                   matlab.ui.control.Button
        LataLamp                     matlab.ui.control.Lamp
        LataLamp_2Label              matlab.ui.control.Label
        IniciarButton                matlab.ui.control.Button
        InicioLamp                   matlab.ui.control.Lamp
        InicioLampLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ard = arduino('COM4','UNO');
        

        sensorPin = 'A0';
        servoPin = 'D4';
        motorPin = 'D6';

        rutaAlarmaBueno = 'Bueno.mp3';  
        correcto = imread('Aceptada.jpg');
        rutaAlarmaMalo = 'Malo.mp3';
        rechazada = imread('Rechazada.jpg');
        sinLata = imread('sinLata.JPG');
        
        stopFlag = false;
        
        valor = 0;
        numItem = int8(0);


        %Neurona
        W = [];
        f11 = [];

        % Historial
        LR = 0;
        LY= 0;
        LB= 0;

        AR = 0;
        AY = 0;
        AB = 0;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear
            clear m;
            close all
            clc
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)


            clear m
            m = mobiledev;
            cam = camera(m,'back');
            cam.Resolution = '640x480';
            cam.Autofocus = 'on';
            cam.Flash = 'on';
            
            app.InicioLamp.Color = "Green"; 
            app.stopFlag = false;
            [audioB, fMB] = audioread(app.rutaAlarmaBueno);
            [audioM, fMM] = audioread(app.rutaAlarmaMalo);
            servoMotor = servo(app.ard,app.servoPin);
            writePosition(servoMotor,0);


            app.UIAxes.Title.String = 'Cámara';
%             time = 0;
%             count = 0;
            EE = [1 1 1; 1 1 1; 1 1 1];
            
            while app.stopFlag==false

                app.LataLamp.Color = "red";
                app.ColordelataLamp.Color = "white";
                imshow('noHayLata.jpeg','Parent',app.UIAxes);
                app.ColorEditField.Value = ' ';
                app.EstadodelataEditField.Value = ' ';
                app.EstatusEditField.Value = 'Sin Lata';
                app.DanioEditField.Value = ' ';


                app.valor = readDigitalPin(app.ard,app.sensorPin);
                valMotor = readVoltage(app.ard,'A1');
%                 app.Gauge.Value=valMotor;
                valMotor = max(0,min(255,(valMotor/5)*255));
                writePWMDutyCycle(app.ard,app.motorPin,valMotor/255);
                
                
                if app.valor == 0

                    writePWMDutyCycle(app.ard,app.motorPin,0);
                    writePosition(servoMotor,0);
                    app.LataLamp.Color = "Green";
                    app.numItem = app.numItem +1;
                        
                    [videoFrame,~] = snapshot(cam,'immediate');
                    frameSize = size(videoFrame);
                    ym = zeros(frameSize(1),frameSize(2));
                    bm = zeros(frameSize(1),frameSize(2));
                    rm = zeros(frameSize(1),frameSize(2));
                    
                    HSV = rgb2hsv(videoFrame);
                    H = 360*(HSV(:,:,1));
                    S = 100*(HSV(:,:,2));
                    V = 100*(HSV(:,:,3));
                    
                    for j=1:frameSize(1)
                     for i=1:frameSize(2)
                         if (S(j,i)>60 && V(j,i)>25)
                             ym(j,i) = (H(j,i)>30 && H(j,i)<90);
                             bm(j,i) = (H(j,i)>190 && H(j,i)<255);
                             rm(j,i) = (H(j,i)>330 || H(j,i)<30);
                         end
                     end
                    end
                    
                    f = 0;
                    colores = [sum(sum(ym)) sum(sum(bm)) sum(sum(rm))];
                    [value, index] = max(colores);
                    
                    if value > 500
                    
                        switch index
                            case 1
                                ym = imopen(ym,EE);
                                f = ym;
                                borde = edge(ym,'log');
                                [o1,o2,o3,o4] = AislarObj(ym,videoFrame);
                                color = 'Amarillo';
                                app.ColorEditField.Value = 'Lata amarilla';
                                app.ColordelataLamp.Color = "Yellow";
                                
                            case 2
                                bm = imopen(bm,EE);
                                f = bm;
                                borde = edge(bm,'log',0);
                                [o1,o2,o3,o4] = AislarObj(bm,videoFrame);
                                color = 'Azul';
                                app.ColorEditField.Value= 'Lata azul';
                                app.ColordelataLamp.Color = "Blue";
                            case 3
                                rm = imopen(rm,EE);
                                f = rm;
                                borde = edge(rm,'log',0);
                                [o1,o2,o3,o4] = AislarObj(rm,videoFrame);
                                color = 'Rojo';
                                app.ColorEditField.Value = 'Lata roja';
                                app.ColordelataLamp.Color = "Red";
                            otherwise
                                app.LataLamp.Color = "Red";
                                app.ColorEditField.Value = 'Sin lata';
                                app.ColordelataLamp.Color = "White";
                        end
                        imshow(videoFrame,'Parent',app.UIAxes);
                        hold(app.UIAxes,'on')
                        rectangle('Position',[o1 o2 o3 o4], ...
                            'LineWidth',2,'EdgeColor','m','Curvature',0.3,'Parent',app.UIAxes);
                        hold(app.UIAxes,'off')
                        [phi1, phi2, phi3, phi4, phi5, phi6, phi7] = hu(f);
                        [c1, c2, c3, c4, c5, c6, c7] = normalizacionMomentos(phi1, phi2, phi3, phi4, phi5, phi6, phi7);
                        PP = [c1 c2 c3 c4 c5 c6 c7]';
                        PP = PP./norm(PP);
                        a = compet(app.W*PP);
                        [~,indexN] = max(a);
                        if indexN == 1
                            sound(audioB, fMB);
                            app.Image.ImageSource = app.correcto;
                            app.Image.Visible = 'on';
                            estado = 'Completa';
                            status = 0;
                            
                            
                            app.EstadodelataEditField.Value = "Completa";

                            writePosition(servoMotor,0);
                            writePWMDutyCycle(app.ard,app.motorPin,valMotor/255);
                            pause(2);
                            switch index
                                case 1
                                    app.LY = app.LY+1;
                                case 2
                                    app.LB = app.LB+1;
                                case 3
                                    app.LR = app.LR+1;
                            end
                        else
                            sound(audioM, fMM);
                            app.Image.ImageSource = app.rechazada;
                            app.Image.Visible = 'on';
                            estado = 'Apachurrada';
                            
                            status = 1;
%                             app.UIAxes.Title.String = 'lata apachurrada';
                            app.EstadodelataEditField.Value = "Apachurrada";
                            writePosition(servoMotor,0.38);
                            writePWMDutyCycle(app.ard,app.motorPin,valMotor/255);
                            pause(2);

                            switch index
                                case 1
                                    app.AY = app.AY+1;
                                case 2
                                    app.AB = app.AB+1;
                                case 3
                                    app.AR = app.AR+1;
                            end
                        end
%                         time = 0;
                        firma = GenerateSign(borde);
                        firma = Preprocessing(app.f11,firma);
                        [dmg] = getDmg(firma);
                        if (status == 0)
                            statusText = 'Aceptada';
                            dmg = 0;
                        else
                            statusText = 'Rechazada';
                        end
                        app.DanioEditField.Value = num2str(dmg);
                        app.EstatusEditField.Value = statusText;
                        dataTable = app.UITable.Data;
                        y = {datestr(date),datestr(timeofday(datetime)),app.numItem,estado,dmg,color,statusText};
                        newData = [dataTable;y];
                        app.UITable.Data = newData;
                        pause(1.5);
                    end    
    
                else
                    
                    app.Image.ImageSource = app.sinLata;
                    app.InicioLamp.Color = "Red";
                    app.Image.Visible = 'on';
%                     imshow(videoFrame,'Parent',app.UIAxes);
                    %                     app.UIAxes.Title.String = 'Sin Lata';

                    app.ColordelataLamp.Color = "white";
                    imshow('noHayLata.jpeg','Parent',app.UIAxes);
                    app.ColorEditField.Value = ' ';
                    app.EstadodelataEditField.Value = ' ';
                    app.EstatusEditField.Value = 'Sin Lata';
                    app.DanioEditField.Value = ' ';
                    
%                     pause(0.001);
%                        time = time+1;
                 end
%                     pause(5)
            end


            TL = app.LR+app.LY+app.LB;
            TA = app.AR+app.AY+app.AB;

            x = {app.LR,app.LY,app.LB,TL;
                app.AR,app.AY,app.AB,TA;
                app.LR+app.AR,app.LY+app.AY,app.LB+app.AB,TL+TA};

            app.UITable_2.Data = x;
            app.stopFlag = true;
            app.InicioLamp.Color = "Red";
            writePWMDutyCycle(app.ard,app.motorPin,0);

            app.Image.ImageSource = app.sinLata;
            app.ColordelataLamp.Color = "white";
            imshow('noHayLata.jpeg','Parent',app.UIAxes);
            app.ColorEditField.Value = ' ';
            app.EstadodelataEditField.Value = ' ';
            app.EstatusEditField.Value = 'Sin Lata';
            app.DanioEditField.Value = ' ';

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;
            app.InicioLamp.Color = "Red";
            app.LataLamp.Color = "Red";
            writePWMDutyCycle(app.ard,app.motorPin,0);
             app.W
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            app.EntrenandoLabel.Visible = 'on';
            app.EntrenandoLabel.Text = 'Entrenando...';
            pause(0.0001);
            load Firmas.mat f1;
            app.f11 = f1;
            load Patterns.mat Patterns;
            app.W = [Patterns(:,1)';Patterns(:,10)';Patterns(:,16)'];
    
            
            for epochs=1:1000
                for j=1:20
                    for i=1:3
                        app.W(i,:) = app.W(i,:)/norm(app.W(i,:));
                    end
                    a = compet(app.W*Patterns(:,j));
                    app.W = app.W+0.0001*a*Patterns(:,j)';
                end
            end
            app.EntrenandoLabel.Text = 'Finalizado';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Camara')
            app.InicioLampLabel = uilabel(app.UIFigure);
            app.InicioLampLabel.Text = 'Inicio';
            app.InicioLamp = uilamp(app.UIFigure);
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.LataLamp_2Label = uilabel(app.UIFigure);
            app.LataLamp_2Label.Text = 'Lata';
            app.LataLamp = uilamp(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Fecha'; 'Hora'; 'iD'; 'Estado de lata'; '% de daño'; 'Color'; 'Estatus'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Objetos Rojos'; 'Objetos Amarillos'; 'Objetos Azules'; 'Total de objetos'};
            app.UITable_2.RowName = {'Lote aceptado'; 'Objetos rechazados'; 'Total'};
            app.HistorialdeactividadesLabel = uilabel(app.UIFigure);
            app.HistorialdeactividadesLabel.Text = 'Historial de actividades';
            app.RecuentogeneralLabel = uilabel(app.UIFigure);
            app.RecuentogeneralLabel.Text = 'Recuento general';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.EntrenandoLabel = uilabel(app.UIFigure);
            app.EntrenandoLabel.Visible = 'off';
            app.EntrenandoLabel.Text = 'Entrenando...';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.ColordelataLampLabel = uilabel(app.UIFigure);
            app.ColordelataLampLabel.Text = 'Color de lata';
            app.ColordelataLamp = uilamp(app.UIFigure);
            app.EstatusEditFieldLabel = uilabel(app.UIFigure);
            app.EstatusEditFieldLabel.Text = 'Estatus';
            app.EstatusEditField = uieditfield(app.UIFigure, 'text');
            app.EstadodelataEditFieldLabel = uilabel(app.UIFigure);
            app.EstadodelataEditFieldLabel.Text = 'Estado de lata';
            app.EstadodelataEditField = uieditfield(app.UIFigure, 'text');
            app.DanioEditFieldLabel = uilabel(app.UIFigure);
            app.DanioEditFieldLabel.Text = 'Danio';
            app.DanioEditField = uieditfield(app.UIFigure, 'text');
            app.ColorEditFieldLabel = uilabel(app.UIFigure);
            app.ColorEditFieldLabel.Text = 'Color';
            app.ColorEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end